classdef expt1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        R10Button                    matlab.ui.control.Button
        Stimuli1Button               matlab.ui.control.Button
        Stimuli2Button               matlab.ui.control.Button
        Stimuli3Button               matlab.ui.control.Button
        Stimuli4Button               matlab.ui.control.Button
        R100Button                   matlab.ui.control.Button
        Slider_2                     matlab.ui.control.Slider
        Slider_3                     matlab.ui.control.Slider
        Slider_4                     matlab.ui.control.Slider
        Slider_5                     matlab.ui.control.Slider
        SaveButton                   matlab.ui.control.Button
        Stimulinumber19SpinnerLabel  matlab.ui.control.Label
        Stimulinumber19Spinner       matlab.ui.control.Spinner
        ListenerNameEditFieldLabel   matlab.ui.control.Label
        ListenerNameEditField        matlab.ui.control.EditField
        DoyounoticemovementLabel     matlab.ui.control.Label
        YesCheckBox_3                matlab.ui.control.CheckBox
        YesCheckBox_4                matlab.ui.control.CheckBox
        YesCheckBox_5                matlab.ui.control.CheckBox
        YesCheckBox_6                matlab.ui.control.CheckBox
        StopButton                   matlab.ui.control.Button
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    
    properties (Access = private)
        R10value;
        W1value;
        W2value;
        W4value;
        W6value;
        R100value; 
        
        R10stimuli;
        W1stimuli;
        W2stimuli;
        W4stimuli;
        W6stimuli;
        R100stimuli; 
       % fileID;
       location;
       trialnum;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SliderValueChanged(app, event)
            %value = app.Slider.Value;
            app.R10value = round(app.Slider.Value); % get then round
            app.Slider.Value = app.R10value; % set
            disp(app.R10value)
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.W1value = round(app.Slider_2.Value); % get then round
            app.Slider_2.Value = app.W1value; % set
            disp(app.W1value)    
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
        app.W2value = round(app.Slider_3.Value); % get then round
        app.Slider_3.Value = app.W2value; % set
        disp(app.W2value) 
            
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
       app.W4value = round(app.Slider_4.Value); % get then round
       app.Slider_4.Value = app.W4value; % set
       disp(app.W4value) 
            
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
       app.W6value = round(app.Slider_5.Value); % get then round
       app.Slider_5.Value = app.W6value; % set
       disp(app.W6value)  
        end

        % Callback function
        function Slider_6ValueChanged(app, event)
       app.R100value = round(app.Slider_6.Value); % get then round
       app.Slider_6.Value = app.R100value; % set
       disp(app.R100value) 
        end

        % Callback function
        function Stimulinumber16EditFieldValueChanged(app, event)
  
        end

        % Callback function
        function StateButtonValueChanged(app, event)
         app.StateButton.Value = app.Stimulinumber16EditField.Value;
        end

        % Button pushed function: R10Button
        function R10ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.R10stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: Stimuli1Button
        function Stimuli1ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.W1stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: Stimuli2Button
        function Stimuli2ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.W2stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: Stimuli3Button
        function Stimuli3ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.W4stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: Stimuli4Button
        function Stimuli4ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.W6stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: R100Button
        function R100ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.R100stimuli) ' &'];
            unix(str2)
        end

        % Value changed function: Stimulinumber19Spinner
        function Stimulinumber19SpinnerValueChanged(app, event)
        fileID = fopen(app.location, 'a');
        fprintf(fileID,'\n\n\n\n Trial Number :');
        fprintf(fileID,'%d\n',app.trialnum);

        
        fprintf(fileID,'____________________________________________\n');
 
        fprintf(fileID,'%s\t %d \n',app.W1stimuli,app.W1value);
        value = app.YesCheckBox_3.Value;
        fprintf(fileID,'%s\t %d \n',"Movement? : ",value);
        fprintf(fileID,'%s\t %d \n',app.W2stimuli,app.W2value);
        value = app.YesCheckBox_4.Value;
        fprintf(fileID,'%s\t %d \n',"Movement? : ",value);
        fprintf(fileID,'%s\t %d \n',app.W4stimuli,app.W4value);
        value = app.YesCheckBox_5.Value;
        fprintf(fileID,'%s\t %d \n',"Movement? : ",value);
        fprintf(fileID,'%s\t %d \n',app.W6stimuli,app.W6value);
        value = app.YesCheckBox_6.Value;
        fprintf(fileID,'%s\t %d \n',"Movement? : ",value);
        fclose(fileID);
        fig = app.UIFigure;
        message = sprintf('Values Saved');
        uialert(fig,message,'Success',...
        'Icon','success');  
            
            app.Slider_5.Value =0;
            app.Slider_4.Value =0;
            app.Slider_3.Value =0;
            app.Slider_2.Value =0;
            app.YesCheckBox_3.Value =false;
            app.YesCheckBox_4.Value =false;
            app.YesCheckBox_5.Value =false;
            app.YesCheckBox_6.Value =false;
            app.W1value=0;
            app.W2value=0;
            app.W4value=0;
            app.W6value=0;
            
            
            app.trialnum = app.Stimulinumber19Spinner.Value;
            if app.trialnum ==1
            disp(app.trialnum)
            app.R10stimuli = "oct8000.wav";
            app.W2stimuli = "W1_natural_vowel_A.wav";
            app.W1stimuli = "W2_natural_vowel_A.wav";
            app.W6stimuli = "W4_natural_vowel_A.wav";
            app.W4stimuli = "W6_natural_vowel_A.wav";
            app.R100stimuli = "R100.wav";
            
            
            elseif app.trialnum==3
            
            disp(app.trialnum)
            app.R10stimuli = "oct8000.wav";
            app.W6stimuli = "W1_A_expt1.wav";
            app.W1stimuli = "W2_A_expt1.wav";
            app.W4stimuli = "W4_A_expt1.wav";
            app.W2stimuli = "W6_A_expt1.wav";
            app.R100stimuli = "R100.wav";
              
            
            elseif app.trialnum==6
                
            app.R10stimuli = "oct8000.wav";
            app.W1stimuli = "W1_natural_vowel_I.wav";
            app.W2stimuli = "W2_natural_vowel_I.wav";
            app.W6stimuli = "W4_natural_vowel_I.wav";
            app.W4stimuli = "W6_natural_vowel_I.wav";
            app.R100stimuli = "R100.wav";
                
            elseif app.trialnum==2
                
            app.R10stimuli = "oct8000.wav";
            app.W4stimuli = "W1_I_expt1.wav";
            app.W6stimuli = "W2_I_expt1.wav";
            app.W2stimuli = "W4_I_expt1.wav";
            app.W1stimuli = "W6_I_expt1.wav";
            app.R100stimuli = "R100.wav";
            
            elseif app.trialnum==5
                
            app.R10stimuli = "oct8000.wav";
            app.W6stimuli = "W1_natural_vowel_U.wav";
            app.W2stimuli = "W2_natural_vowel_U.wav";
            app.W4stimuli = "W4_natural_vowel_U.wav";
            app.W1stimuli = "W6_natural_vowel_U.wav";
            app.R100stimuli = "R100.wav";
                
            elseif app.trialnum==4
             
            app.R10stimuli = "oct8000.wav";
            app.W4stimuli = "W1_U_expt1.wav";
            app.W1stimuli = "W2_U_expt1.wav";
            app.W6stimuli = "W4_U_expt1.wav";
            app.W2stimuli = "W6_U_expt1.wav";
            app.R100stimuli = "R100.wav";
            
            elseif app.trialnum==7
             
            app.R10stimuli = "oct8000.wav";
            app.W1stimuli = "W1_pitchchangeA_expt1.wav";
            app.W2stimuli = "W2_pitchchangeA_expt1.wav";
            app.W4stimuli = "W4_pitchchangeA_expt1.wav";
            app.W6stimuli = "W6_pitchchangeA_expt1.wav";
            app.R100stimuli = "R100.wav";
            
            elseif app.trialnum==8
             
            app.R10stimuli = "oct8000.wav";
            app.W6stimuli = "W1_AI_expt1.wav";
            app.W4stimuli = "W2_AI_expt1.wav";
            app.W2stimuli = "W4_AI_expt1.wav";
            app.W1stimuli = "W6_AI_expt1.wav";
            app.R100stimuli = "R100.wav";
            
            elseif app.trialnum==9
            
            app.R10stimuli = "oct8000.wav";
            app.W6stimuli = "W1_AI_pitchchange_expt1.wav";
            app.W4stimuli = "W2_AI_pitchchange_expt1.wav";
            app.W2stimuli = "W4_AI_pitchchange_expt1.wav";
            app.W1stimuli = "W6_AI_pitchchange_expt1.wav";
            app.R100stimuli = "R100.wav";
            
            else
                disp("stimuli number wrong")
            end
            
        end

        % Callback function
        function ListenernameEditFieldValueChanged(app, event)
         value = app.ListenernameEditField.Value;   
         root =   '/home/kodhandarama/Desktop/Desktop/Analog/Shreeram_Chandra/shreeram/python_work/python_sounddevice/expt1_results';
         location =strcat(root,value);

        end

        % Value changed function: ListenerNameEditField
        function ListenerNameEditFieldValueChanged(app, event)
            value = app.ListenerNameEditField.Value;
            root =   '/home/kodhandarama/Desktop/Desktop/Analog/Shreeram_Chandra/shreeram/python_work/python_sounddevice/expt1_results/';
            app.location =strcat(root,value,'.txt')
            
            str = ['cd ' root];
            unix(str)
            %str2 = ['mkdir ' app.location];
            %unix(str2)
            
            fileID = fopen(app.location, 'a');

            fprintf(fileID,'%s \n',value);
            fclose(fileID);
            
            str3 = ['cd ' '/home/kodhandarama/Desktop/Desktop/Analog/Shreeram_Chandra/shreeram/python_work/python_sounddevice/Expt2/code/expt1'];
            unix(str3)
            
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        fileID = fopen(app.location, 'a');
        fprintf(fileID,'\n\n\n\n Trial Number :');
        fprintf(fileID,'%d\n',app.trialnum);

        
        fprintf(fileID,'____________________________________________\n');
 
        fprintf(fileID,'%s\t %d \n',app.W1stimuli,app.W1value);
        value = app.YesCheckBox_3.Value;
        fprintf(fileID,'%s\t %d \n',"Movement? : ",value);
        fprintf(fileID,'%s\t %d \n',app.W2stimuli,app.W2value);
        value = app.YesCheckBox_4.Value;
        fprintf(fileID,'%s\t %d \n',"Movement? : ",value);
        fprintf(fileID,'%s\t %d \n',app.W4stimuli,app.W4value);
        value = app.YesCheckBox_5.Value;
        fprintf(fileID,'%s\t %d \n',"Movement? : ",value);
        fprintf(fileID,'%s\t %d \n',app.W6stimuli,app.W6value);
        value = app.YesCheckBox_6.Value;
        fprintf(fileID,'%s\t %d \n',"Movement? : ",value);
        fclose(fileID);
        
        fig = app.UIFigure;
        message = sprintf('Values Saved');
        uialert(fig,message,'Success',...
        'Icon','success');  
        
        %W2value;
        %W4value;
        %W6value;
        %R100value; 
        end

        % Value changed function: YesCheckBox_3
        function YesCheckBox_3ValueChanged(app, event)
            value = app.YesCheckBox_3.Value;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            disp(selectedButton)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
       
        fileID = fopen(app.location, 'a');
        fprintf(fileID,'\n\n\n\n Trial Number :');
        fprintf(fileID,'%d\n',app.trialnum);

        
        fprintf(fileID,'____________________________________________\n');
 
        fprintf(fileID,'%s\t %d \n',app.W1stimuli,app.W1value);
        value = app.YesCheckBox_3.Value;
        fprintf(fileID,'%s\t %d \n',"Movement? : ",value);
        fprintf(fileID,'%s\t %d \n',app.W2stimuli,app.W2value);
        value = app.YesCheckBox_4.Value;
        fprintf(fileID,'%s\t %d \n',"Movement? : ",value);
        fprintf(fileID,'%s\t %d \n',app.W4stimuli,app.W4value);
        value = app.YesCheckBox_5.Value;
        fprintf(fileID,'%s\t %d \n',"Movement? : ",value);
        fprintf(fileID,'%s\t %d \n',app.W6stimuli,app.W6value);
        value = app.YesCheckBox_6.Value;
        fprintf(fileID,'%s\t %d \n',"Movement? : ",value);
        fclose(fileID);


            delete(app)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.R10Button = uibutton(app.UIFigure, 'push');
            app.R10Button.ButtonPushedFcn = createCallbackFcn(app, @R10ButtonPushed, true);
            app.R10Button.Text = 'R10';
            app.Stimuli1Button = uibutton(app.UIFigure, 'push');
            app.Stimuli1Button.ButtonPushedFcn = createCallbackFcn(app, @Stimuli1ButtonPushed, true);
            app.Stimuli1Button.Text = {'Stimuli 1'; ''};
            app.Stimuli2Button = uibutton(app.UIFigure, 'push');
            app.Stimuli2Button.ButtonPushedFcn = createCallbackFcn(app, @Stimuli2ButtonPushed, true);
            app.Stimuli2Button.Text = {'Stimuli 2'; ''};
            app.Stimuli3Button = uibutton(app.UIFigure, 'push');
            app.Stimuli3Button.ButtonPushedFcn = createCallbackFcn(app, @Stimuli3ButtonPushed, true);
            app.Stimuli3Button.Text = 'Stimuli 3';
            app.Stimuli4Button = uibutton(app.UIFigure, 'push');
            app.Stimuli4Button.ButtonPushedFcn = createCallbackFcn(app, @Stimuli4ButtonPushed, true);
            app.Stimuli4Button.Text = 'Stimuli 4';
            app.R100Button = uibutton(app.UIFigure, 'push');
            app.R100Button.ButtonPushedFcn = createCallbackFcn(app, @R100ButtonPushed, true);
            app.R100Button.Text = 'R100';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 120];
            app.Slider_2.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 120];
            app.Slider_3.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [0 120];
            app.Slider_4.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [0 120];
            app.Slider_5.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Stimulinumber19SpinnerLabel = uilabel(app.UIFigure);
            app.Stimulinumber19SpinnerLabel.Text = 'Stimuli number (1 - 9) :';
            app.Stimulinumber19Spinner = uispinner(app.UIFigure);
            app.Stimulinumber19Spinner.Limits = [0 6];
            app.Stimulinumber19Spinner.ValueChangedFcn = createCallbackFcn(app, @Stimulinumber19SpinnerValueChanged, true);
            app.ListenerNameEditFieldLabel = uilabel(app.UIFigure);
            app.ListenerNameEditFieldLabel.Text = 'Listener Name :';
            app.ListenerNameEditField = uieditfield(app.UIFigure, 'text');
            app.ListenerNameEditField.ValueChangedFcn = createCallbackFcn(app, @ListenerNameEditFieldValueChanged, true);
            app.DoyounoticemovementLabel = uilabel(app.UIFigure);
            app.DoyounoticemovementLabel.Text = {'Do you notice movement?'; ''};
            app.YesCheckBox_3 = uicheckbox(app.UIFigure);
            app.YesCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @YesCheckBox_3ValueChanged, true);
            app.YesCheckBox_3.Text = {'Yes'; ''};
            app.YesCheckBox_4 = uicheckbox(app.UIFigure);
            app.YesCheckBox_4.Text = {'Yes'; ''};
            app.YesCheckBox_5 = uicheckbox(app.UIFigure);
            app.YesCheckBox_5.Text = {'Yes'; ''};
            app.YesCheckBox_6 = uicheckbox(app.UIFigure);
            app.YesCheckBox_6.Text = {'Yes'; ''};
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = expt1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end