classdef expt2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        R10Button                    matlab.ui.control.Button
        Stimuli1Button               matlab.ui.control.Button
        Stimuli2Button               matlab.ui.control.Button
        Stimuli3Button               matlab.ui.control.Button
        Stimuli4Button               matlab.ui.control.Button
        R100Button                   matlab.ui.control.Button
        Slider_2                     matlab.ui.control.Slider
        Slider_3                     matlab.ui.control.Slider
        Slider_4                     matlab.ui.control.Slider
        Slider_5                     matlab.ui.control.Slider
        Stimulinumber13SpinnerLabel  matlab.ui.control.Label
        Stimulinumber13Spinner       matlab.ui.control.Spinner
        ListenerNameEditFieldLabel   matlab.ui.control.Label
        ListenerNameEditField        matlab.ui.control.EditField
        StopButton                   matlab.ui.control.Button
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
        Slider_11                    matlab.ui.control.Slider
        Slider_10                    matlab.ui.control.Slider
        Slider_8                     matlab.ui.control.Slider
        Source1Label                 matlab.ui.control.Label
        Source2Label                 matlab.ui.control.Label
        Slider_9                     matlab.ui.control.Slider
    end

    
    properties (Access = private)
        R10value_s1;
        R10value_s2;
        W1value_s1;
        W1value_s2;
        W2value_s1;
        W2value_s2;
        W4value_s1;
        W4value_s2;
        W6value_s1;
        W6value_s2;
        R100value_s1;
        R100value_s2;
        
        R10stimuli;
        W1stimuli;
        W2stimuli;
        W4stimuli;
        W6stimuli;
        R100stimuli; 
       % fileID;
       location;
       trialnum;
       currentspinner;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SliderValueChanged(app, event)
            %value = app.Slider.Value;
            app.R10value_s1 = round(app.Slider.Value); % get then round
            app.Slider.Value = app.R10value_s1; % set
            disp(app.R10value_s1)
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.W1value_s1 = round(app.Slider_2.Value); % get then round
            app.Slider_2.Value = app.W1value_s1; % set
            disp(app.W1value_s1)    
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
        app.W2value_s1 = round(app.Slider_3.Value); % get then round
        app.Slider_3.Value = app.W2value_s1; % set
        disp(app.W2value_s1) 
            
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
       app.W4value_s1 = round(app.Slider_4.Value); % get then round
       app.Slider_4.Value = app.W4value_s1; % set
       disp(app.W4value_s1) 
            
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
       app.W6value_s1 = round(app.Slider_5.Value); % get then round
       app.Slider_5.Value = app.W6value_s1; % set
       disp(app.W6value_s1)  
        end

        % Callback function
        function Slider_6ValueChanged(app, event)
       app.R100value_s1 = round(app.Slider_6.Value); % get then round
       app.Slider_6.Value = app.R100value_s1; % set
       disp(app.R100value_s1) 
        end

        % Callback function
        function Stimulinumber16EditFieldValueChanged(app, event)
          
        end

        % Callback function
        function StateButtonValueChanged(app, event)
         app.StateButton.Value = app.Stimulinumber16EditField.Value;
        end

        % Button pushed function: R10Button
        function R10ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.R10stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: Stimuli1Button
        function Stimuli1ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.W1stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: Stimuli2Button
        function Stimuli2ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.W2stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: Stimuli3Button
        function Stimuli3ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.W4stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: Stimuli4Button
        function Stimuli4ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.W6stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: R100Button
        function R100ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.R100stimuli) ' &']
            unix(str2)
        end

        % Value changed function: Stimulinumber13Spinner
        function Stimulinumber13SpinnerValueChanged(app, event)
        msg = "Make sure you've rated both the scales. Do you want to save the current ratings?";
         fig = app.UIFigure;
         title = 'Confirm Save';
        selection = uiconfirm(fig,msg,title,'Options',{'Save','Change trial without saving','Check current trial ratings again'},'DefaultOption',1);
        if(selection == "Save")
        app.currentspinner = app.Stimulinumber13Spinner.Value;
        fileID = fopen(app.location, 'a');
        fprintf(fileID,'\n\n\n\n Trial Number :');
        fprintf(fileID,'%d\n',app.trialnum);
        fprintf(fileID,'____________________________________________\n');
        fprintf(fileID,'%s \n','Source 1 :');
        %fprintf(fileID,'%s\t %d \n','R10 :',app.R10value_s1);
        fprintf(fileID,'%s\t %d \n','W1 :',app.W1value_s1);
        fprintf(fileID,'%s\t %d \n','W2 :',app.W2value_s1);
        fprintf(fileID,'%s\t %d \n','W4 :',app.W4value_s1);
        fprintf(fileID,'%s\t %d \n','W6 :',app.W6value_s1);
        %fprintf(fileID,'%s\t %d ','R100 :',app.R100value_s1);
        fprintf(fileID,'\n\n\n%s\n','Source 2 :');
        %fprintf(fileID,'%s\t %d \n','R10 :',app.R10value_s2);
        fprintf(fileID,'%s\t %d \n','W1 :',app.W1value_s2); 
        fprintf(fileID,'%s\t %d \n','W2 :',app.W2value_s2);
        fprintf(fileID,'%s\t %d \n','W4 :',app.W4value_s2);
        fprintf(fileID,'%s\t %d \n','W6 :',app.W6value_s2);
        %fprintf(fileID,'%s\t %d \n','R100 :',app.R100value_s2);
        fclose(fileID);
        fig = app.UIFigure;
        message = sprintf('Values Saved');
        uialert(fig,message,'Success',...
        'Icon','success');  
            
            app.Slider_2.Value =0;
            app.Slider_3.Value =0;
            app.Slider_4.Value =0;
            app.Slider_5.Value =0;
            
            app.Slider_8.Value =-10;
            app.Slider_9.Value =-10;
            app.Slider_10.Value =-10;
            app.Slider_11.Value =-10;
           
         
            app.W1value_s1=0;
            app.W2value_s1=0;
            app.W4value_s1=0;
            app.W6value_s1=0;
            
            app.W1value_s2=-10;
            app.W2value_s2=-10;
            app.W4value_s2=-10;
            app.W6value_s2=-10;


            app.trialnum = app.Stimulinumber13Spinner.Value;
            value = app.trialnum; 
            if value ==1
            app.R10stimuli = "oct8000.wav";
            app.W6stimuli = "A_sigma1_minus30dB_expt2.wav";
            app.W4stimuli = "A_sigma1_minus10dB_expt2.wav";
            app.W2stimuli = "A_sigma1_0dB_expt2.wav";
            app.W1stimuli = "A_sigma1_minus20dB_expt2.wav";
            app.R100stimuli = "R100.wav";
            
            
            elseif value==2
            
            disp(value)
            app.R10stimuli = "oct8000.wav";
            app.W6stimuli = "I_sigma1_0dB_expt2.wav";
            app.W4stimuli = "I_sigma1_minus20dB_expt2.wav";
            app.W2stimuli = "I_sigma1_minus10dB_expt2.wav";
            app.W1stimuli = "I_sigma1_minus30dB_expt2.wav";
            app.R100stimuli = "R100.wav";
              
            
            elseif value==3
                
            disp(value)
            app.R10stimuli = "oct8000.wav";
            app.W6stimuli = "U_sigma1_minus30dB_expt2.wav";
            app.W4stimuli = "U_sigma1_minus10dB_expt2.wav";
            app.W2stimuli = "U_sigma1_minus20dB_expt2.wav";
            app.W1stimuli = "U_sigma1_0dB_expt2.wav";
            app.R100stimuli = "R100.wav";    
%             elseif value==4
%                 
%              disp(value)
%             app.R10stimuli = "oct8000.wav";
%             app.W6stimuli = "";
%             app.W4stimuli = "";
%             app.W2stimuli = "";
%             app.W1stimuli = "";
%             app.R100stimuli = "R100.wav";
%             
%             elseif value==5
%                 
%              disp(value)
%             app.R10stimuli = "oct8000.wav";
%             app.W6stimuli = "";
%             app.W4stimuli = "";
%             app.W2stimuli = "";
%             app.W1stimuli = "";
%             app.R100stimuli = "R100.wav";
%                 
%             elseif value==6
%              
%             disp(value)
%             app.R10stimuli = "oct8000.wav";
%             app.W6stimuli = "";
%             app.W4stimuli = "";
%             app.W2stimuli = "";
%             app.W1stimuli = "";
%             app.R100stimuli = "R100.wav";
%             
%              elseif value==7
%              
%             disp(value)
%             app.R10stimuli = "oct8000.wav";
%             app.W6stimuli = "";
%             app.W4stimuli = "";
%             app.W2stimuli = "";
%             app.W1stimuli = "";
%             app.R100stimuli = "R100.wav";
%             
%              elseif value==8
%              
%             disp(value)
%             app.R10stimuli = "oct8000.wav";
%             app.W6stimuli = "";
%             app.W4stimuli = "";
%             app.W2stimuli = "";
%             app.W1stimuli = "";
%             app.R100stimuli = "R100.wav";
%             
%              elseif value==9
%              
%             disp(value)
%             app.R10stimuli = "oct8000.wav";
%             app.W6stimuli = "";
%             app.W4stimuli = "";
%             app.W2stimuli = "";
%             app.W1stimuli = "";
%             app.R100stimuli = "R100.wav";
            elseif(app.Stimulinumber13Spinner.Value == 0)
            app.trialnum = app.Stimulinumber13Spinner.Value;
            value = app.trialnum; 
            
            app.R10stimuli = "oct8000.wav";
            app.W6stimuli = "A_sigma1_minus30dB_expt2.wav";
            app.W4stimuli = "A_sigma1_minus10dB_expt2.wav";
            app.W2stimuli = "A_sigma1_0dB_expt2.wav";
            app.W1stimuli = "A_sigma1_minus20dB_expt2.wav";
            app.R100stimuli = "R100.wav";
            else
                disp("stimuli number wrong")
            end
            
        elseif(selection == "Check current trial ratings again")
            disp("Same stimuli and ratings displayed")
            app.Stimulinumber13Spinner.Value= app.currentspinner;
        elseif(selection =="Change trial without saving")
            disp("Trial Changed")
             app.currentspinner = app.Stimulinumber13Spinner.Value;
            app.Slider_2.Value =0;
            app.Slider_3.Value =0;
            app.Slider_4.Value =0;
            app.Slider_5.Value =0;
            
            app.Slider_8.Value =-10;
            app.Slider_9.Value =-10;
            app.Slider_10.Value =-10;
            app.Slider_11.Value =-10;
           
         
            app.W1value_s1=0;
            app.W2value_s1=0;
            app.W4value_s1=0;
            app.W6value_s1=0;
            
            app.W1value_s2=-10;
            app.W2value_s2=-10;
            app.W4value_s2=-10;
            app.W6value_s2=-10;


            app.trialnum = app.Stimulinumber13Spinner.Value;
            value = app.trialnum; 
            if value ==1
            app.R10stimuli = "oct8000.wav";
            app.W6stimuli = "A_sigma1_minus30dB_expt2.wav";
            app.W4stimuli = "A_sigma1_minus10dB_expt2.wav";
            app.W2stimuli = "A_sigma1_0dB_expt2.wav";
            app.W1stimuli = "A_sigma1_minus20dB_expt2.wav";
            app.R100stimuli = "R100.wav";
            
            
            elseif value==2
            
            disp(value)
            app.R10stimuli = "oct8000.wav";
            app.W6stimuli = "I_sigma1_0dB_expt2.wav";
            app.W4stimuli = "I_sigma1_minus20dB_expt2.wav";
            app.W2stimuli = "I_sigma1_minus10dB_expt2.wav";
            app.W1stimuli = "I_sigma1_minus30dB_expt2.wav";
            app.R100stimuli = "R100.wav";
              
            
            elseif value==3
                
            disp(value)
            app.R10stimuli = "oct8000.wav";
            app.W6stimuli = "U_sigma1_minus30dB_expt2.wav";
            app.W4stimuli = "U_sigma1_minus10dB_expt2.wav";
            app.W2stimuli = "U_sigma1_minus20dB_expt2.wav";
            app.W1stimuli = "U_sigma1_0dB_expt2.wav";
            app.R100stimuli = "R100.wav";
            end
        else
            disp("Invalid option")
        end
        end

        % Callback function
        function ListenernameEditFieldValueChanged(app, event)
         value = app.ListenernameEditField.Value;   
         root =   '/home/kodhandarama/Desktop/Desktop/Analog/Shreeram_Chandra/shreeram/python_work/python_sounddevice/expt_2_results/';
         app.location =strcat(root,value);

        end

        % Value changed function: ListenerNameEditField
        function ListenerNameEditFieldValueChanged(app, event)
            value = app.ListenerNameEditField.Value;
            root =   '/home/kodhandarama/Desktop/Desktop/Analog/Shreeram_Chandra/shreeram/python_work/python_sounddevice/expt_2_results/';
            app.location =strcat(root,value,'.txt')
            
            str = ['cd ' root];
            unix(str)
            %str2 = ['mkdir ' app.location];
            %unix(str2)
            
            fileID = fopen(app.location, 'a');

            fprintf(fileID,'%s \n',value);
            fclose(fileID);
            
            str3 = ['cd ' '/home/kodhandarama/Desktop/Desktop/Analog/Shreeram_Chandra/shreeram/python_work/python_sounddevice/Expt2/code/expt2'];
            unix(str3)
            
            
        end

        % Callback function
        function SaveButtonPushed(app, event)
        fileID = fopen(app.location, 'a');
        fprintf(fileID,'\n\n\n\n Trial Number :');
        fprintf(fileID,'%d\n',app.trialnum);
        fprintf(fileID,'____________________________________________\n');
        fprintf(fileID,'%s \n','Source 1 :');
        %fprintf(fileID,'%s\t %d \n','R10 :',app.R10value_s1);
        fprintf(fileID,'%s\t %d \n','W1 :',app.W1value_s1);
        fprintf(fileID,'%s\t %d \n','W2 :',app.W2value_s1);
        fprintf(fileID,'%s\t %d \n','W4 :',app.W4value_s1);
        fprintf(fileID,'%s\t %d \n','W6 :',app.W6value_s1);
        %fprintf(fileID,'%s\t %d ','R100 :',app.R100value_s1);
        fprintf(fileID,'\n\n\n%s\n','Source 2 :');
        %fprintf(fileID,'%s\t %d \n','R10 :',app.R10value_s2);
        fprintf(fileID,'%s\t %d \n','W1 :',app.W1value_s2);
        fprintf(fileID,'%s\t %d \n','W2 :',app.W2value_s2);
        fprintf(fileID,'%s\t %d \n','W4 :',app.W4value_s2);
        fprintf(fileID,'%s\t %d \n','W6 :',app.W6value_s2);
        %fprintf(fileID,'%s\t %d \n','R100 :',app.R100value_s2);
        fclose(fileID);
        
        
        fig = app.UIFigure;
        message = sprintf('Values Saved');
        uialert(fig,message,'Success',...
        'Icon','success');  
        
        %W2value;
        %W4value;
        %W6value;
        %R100value; 
        end

        % Callback function
        function Slider_7ValueChanged(app, event)
           
       app.R10value_s2 = round(app.Slider_7.Value); % get then round
       app.Slider_7.Value = app.R10value_s2; % set
       disp(app.R10value_s2) 
        end

        % Value changed function: Slider_8
        function Slider_8ValueChanged(app, event)
       app.W1value_s2 = round(app.Slider_8.Value); % get then round
       app.Slider_8.Value = app.W1value_s2; % set
       disp(app.W1value_s2)
            
        end

        % Value changed function: Slider_9
        function Slider_9ValueChanged(app, event)
       app.W2value_s2 = round(app.Slider_9.Value); % get then round
       app.Slider_9.Value = app.W2value_s2; % set
       disp(app.W2value_s2)
            
        end

        % Value changed function: Slider_10
        function Slider_10ValueChanged(app, event)
            app.W4value_s2 = round(app.Slider_10.Value); % get then round
            app.Slider_10.Value = app.W4value_s2; % set
            disp(app.W4value_s2)
        end

        % Value changed function: Slider_11
        function Slider_11ValueChanged(app, event)
       app.W6value_s2 = round(app.Slider_11.Value); % get then round
       app.Slider_11.Value = app.W6value_s2; % set
       disp(app.W6value_s2)
            
        end

        % Callback function
        function Slider_12ValueChanged(app, event)
       app.R100value_s2 = round(app.Slider_12.Value); % get then round
       app.Slider_12.Value = app.R100value_s2; % set
       disp(app.R100value_s2)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        fileID = fopen(app.location, 'a');
        fprintf(fileID,'\n\n\n\n Trial Number :');
        fprintf(fileID,'%d\n',app.trialnum);
        fprintf(fileID,'____________________________________________\n');
        fprintf(fileID,'%s \n','Source 1 :');
        %fprintf(fileID,'%s\t %d \n','R10 :',app.R10value_s1);
        fprintf(fileID,'%s\t %d \n','W1 :',app.W1value_s1);
        fprintf(fileID,'%s\t %d \n','W2 :',app.W2value_s1);
        fprintf(fileID,'%s\t %d \n','W4 :',app.W4value_s1);
        fprintf(fileID,'%s\t %d \n','W6 :',app.W6value_s1);
       % fprintf(fileID,'%s\t %d ','R100 :',app.R100value_s1);
        fprintf(fileID,'\n\n\n%s\n','Source 2 :');
        %fprintf(fileID,'%s\t %d \n','R10 :',app.R10value_s2);
        fprintf(fileID,'%s\t %d \n','W1 :',app.W1value_s2);
        fprintf(fileID,'%s\t %d \n','W2 :',app.W2value_s2);
        fprintf(fileID,'%s\t %d \n','W4 :',app.W4value_s2);
        fprintf(fileID,'%s\t %d \n','W6 :',app.W6value_s2);
        %fprintf(fileID,'%s\t %d \n','R100 :',app.R100value_s2);
        fclose(fileID);

            delete(app)
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.R10Button = uibutton(app.UIFigure, 'push');
            app.R10Button.ButtonPushedFcn = createCallbackFcn(app, @R10ButtonPushed, true);
            app.R10Button.Text = 'R10';
            app.Stimuli1Button = uibutton(app.UIFigure, 'push');
            app.Stimuli1Button.ButtonPushedFcn = createCallbackFcn(app, @Stimuli1ButtonPushed, true);
            app.Stimuli1Button.Text = {'Stimuli 1'; ''};
            app.Stimuli2Button = uibutton(app.UIFigure, 'push');
            app.Stimuli2Button.ButtonPushedFcn = createCallbackFcn(app, @Stimuli2ButtonPushed, true);
            app.Stimuli2Button.Text = {'Stimuli 2'; ''};
            app.Stimuli3Button = uibutton(app.UIFigure, 'push');
            app.Stimuli3Button.ButtonPushedFcn = createCallbackFcn(app, @Stimuli3ButtonPushed, true);
            app.Stimuli3Button.Text = 'Stimuli 3';
            app.Stimuli4Button = uibutton(app.UIFigure, 'push');
            app.Stimuli4Button.ButtonPushedFcn = createCallbackFcn(app, @Stimuli4ButtonPushed, true);
            app.Stimuli4Button.Text = {'Stimuli 4'; ''};
            app.R100Button = uibutton(app.UIFigure, 'push');
            app.R100Button.ButtonPushedFcn = createCallbackFcn(app, @R100ButtonPushed, true);
            app.R100Button.Text = 'R100';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 120];
            app.Slider_2.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 120];
            app.Slider_3.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [0 120];
            app.Slider_4.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [0 120];
            app.Slider_5.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Stimulinumber13SpinnerLabel = uilabel(app.UIFigure);
            app.Stimulinumber13SpinnerLabel.Text = 'Stimuli number (1 - 3) :';
            app.Stimulinumber13Spinner = uispinner(app.UIFigure);
            app.Stimulinumber13Spinner.Limits = [0 3];
            app.Stimulinumber13Spinner.ValueChangedFcn = createCallbackFcn(app, @Stimulinumber13SpinnerValueChanged, true);
            app.ListenerNameEditFieldLabel = uilabel(app.UIFigure);
            app.ListenerNameEditFieldLabel.Text = 'Listener Name :';
            app.ListenerNameEditField = uieditfield(app.UIFigure, 'text');
            app.ListenerNameEditField.ValueChangedFcn = createCallbackFcn(app, @ListenerNameEditFieldValueChanged, true);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.Slider_11 = uislider(app.UIFigure);
            app.Slider_11.Limits = [-10 120];
            app.Slider_11.MajorTicks = [-10 0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_11.ValueChangedFcn = createCallbackFcn(app, @Slider_11ValueChanged, true);
            app.Slider_11.Value = -10;
            app.Slider_10 = uislider(app.UIFigure);
            app.Slider_10.Limits = [-10 120];
            app.Slider_10.MajorTicks = [-10 0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_10.ValueChangedFcn = createCallbackFcn(app, @Slider_10ValueChanged, true);
            app.Slider_10.Value = -10;
            app.Slider_8 = uislider(app.UIFigure);
            app.Slider_8.Limits = [-10 120];
            app.Slider_8.MajorTicks = [-10 0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_8.ValueChangedFcn = createCallbackFcn(app, @Slider_8ValueChanged, true);
            app.Slider_8.Value = -10;
            app.Source1Label = uilabel(app.UIFigure);
            app.Source1Label.Text = 'Source 1';
            app.Source2Label = uilabel(app.UIFigure);
            app.Source2Label.Text = 'Source 2';
            app.Slider_9 = uislider(app.UIFigure);
            app.Slider_9.Limits = [-10 120];
            app.Slider_9.MajorTicks = [-10 0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_9.ValueChangedFcn = createCallbackFcn(app, @Slider_9ValueChanged, true);
            app.Slider_9.Value = -10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = expt2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end