classdef expt3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        R10Button                    matlab.ui.control.Button
        W1Button                     matlab.ui.control.Button
        W2Button                     matlab.ui.control.Button
        W4Button                     matlab.ui.control.Button
        W6Button                     matlab.ui.control.Button
        R100Button                   matlab.ui.control.Button
        Slider                       matlab.ui.control.Slider
        Slider_2                     matlab.ui.control.Slider
        Slider_3                     matlab.ui.control.Slider
        Slider_4                     matlab.ui.control.Slider
        Slider_5                     matlab.ui.control.Slider
        Slider_6                     matlab.ui.control.Slider
        SaveButton                   matlab.ui.control.Button
        Stimulinumber13SpinnerLabel  matlab.ui.control.Label
        Stimulinumber13Spinner       matlab.ui.control.Spinner
        ListenerNameEditFieldLabel   matlab.ui.control.Label
        ListenerNameEditField        matlab.ui.control.EditField
        Slider_12                    matlab.ui.control.Slider
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
        Slider_11                    matlab.ui.control.Slider
        Slider_7                     matlab.ui.control.Slider
        Slider_10                    matlab.ui.control.Slider
        Slider_8                     matlab.ui.control.Slider
        Source1Label                 matlab.ui.control.Label
        Source2Label                 matlab.ui.control.Label
        Slider_9                     matlab.ui.control.Slider
    end

    
    properties (Access = private)
        R10value_s1;
        R10value_s2;
        W1value_s1;
        W1value_s2;
        W2value_s1;
        W2value_s2;
        W4value_s1;
        W4value_s2;
        W6value_s1;
        W6value_s2;
        R100value_s1;
        R100value_s2;
        
        R10stimuli;
        W1stimuli;
        W2stimuli;
        W4stimuli;
        W6stimuli;
        R100stimuli; 
       % fileID;
       location;
       trialnum;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            %value = app.Slider.Value;
            app.R10value_s1 = round(app.Slider.Value); % get then round
            app.Slider.Value = app.R10value_s1; % set
            disp(app.R10value_s1)
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.W1value_s1 = round(app.Slider_2.Value); % get then round
            app.Slider_2.Value = app.W1value_s1; % set
            disp(app.W1value_s1)    
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
        app.W2value_s1 = round(app.Slider_3.Value); % get then round
        app.Slider_3.Value = app.W2value_s1; % set
        disp(app.W2value_s1) 
            
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
       app.W4value_s1 = round(app.Slider_4.Value); % get then round
       app.Slider_4.Value = app.W4value_s1; % set
       disp(app.W4value_s1) 
            
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
       app.W6value_s1 = round(app.Slider_5.Value); % get then round
       app.Slider_5.Value = app.W6value_s1; % set
       disp(app.W6value_s1)  
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
       app.R100value_s1 = round(app.Slider_6.Value); % get then round
       app.Slider_6.Value = app.R100value_s1; % set
       disp(app.R100value_s1) 
        end

        % Callback function
        function Stimulinumber16EditFieldValueChanged(app, event)
            value = app.Stimulinumber16EditField.Value;
            if value ==1
            disp(value)
            app.R10stimuli = "W1_synthetic_vowel_A.wav";
            app.W1stimuli = "W1_natural_vowel_A.wav";
            app.W2stimuli = "W2_natural_vowel_A.wav";
            app.W4stimuli = "W4_natural_vowel_A.wav";
            app.W6stimuli = "W6_natural_vowel_A.wav";
            app.R100stimuli = "W6_natural_vowel_A.wav";
            
            
            elseif value==2
            
            disp(value)
            app.R10stimuli = "W1_synthetic_vowel_A.wav";
            app.W1stimuli = "W1_synthetic_vowel_A.wav";
            app.W2stimuli = "W2_synthetic_vowel_A.wav";
            app.W4stimuli = "W4_synthetic_vowel_A.wav";
            app.W6stimuli = "W6_synthetic_vowel_A.wav";
            app.R100stimuli = "W6_natural_vowel_A.wav";
              
            
            elseif value==3
                
            app.R10stimuli = "W1_synthetic_vowel_I.wav";
            app.W1stimuli = "W1_natural_vowel_I.wav";
            app.W2stimuli = "W2_natural_vowel_I.wav";
            app.W4stimuli = "W4_natural_vowel_I.wav";
            app.W6stimuli = "W6_natural_vowel_I.wav";
            app.R100stimuli = "W6_natural_vowel_I.wav";
                
            elseif value==4
                
            app.R10stimuli = "W1_synthetic_vowel_I.wav";
            app.W1stimuli = "W1_synthetic_vowel_I.wav";
            app.W2stimuli = "W2_synthetic_vowel_I.wav";
            app.W4stimuli = "W4_synthetic_vowel_I.wav";
            app.W6stimuli = "W6_synthetic_vowel_I.wav";
            app.R100stimuli = "W6_natural_vowel_I.wav";
            
            elseif value==5
                
            app.R10stimuli = "W1_synthetic_vowel_U.wav";
            app.W1stimuli = "W1_natural_vowel_U.wav";
            app.W2stimuli = "W2_natural_vowel_U.wav";
            app.W4stimuli = "W4_natural_vowel_U.wav";
            app.W6stimuli = "W6_natural_vowel_U.wav";
            app.R100stimuli = "W6_natural_vowel_U.wav";
                
            elseif value==6
             
            app.R10stimuli = "W1_synthetic_vowel_U.wav";
            app.W1stimuli = "W1_synthetic_vowel_U.wav";
            app.W2stimuli = "W2_synthetic_vowel_U.wav";
            app.W4stimuli = "W4_synthetic_vowel_U.wav";
            app.W6stimuli = "W6_synthetic_vowel_U.wav";
            app.R100stimuli = "W6_natural_vowel_U.wav";
            
            else
                disp("stimuli number wrong")
            end
        end

        % Callback function
        function StateButtonValueChanged(app, event)
         app.StateButton.Value = app.Stimulinumber16EditField.Value;
        end

        % Button pushed function: R10Button
        function R10ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.R10stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: W1Button
        function W1ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.W1stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: W2Button
        function W2ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.W2stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: W4Button
        function W4ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.W4stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: W6Button
        function W6ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.W6stimuli) ' &'];
            unix(str2)
        end

        % Button pushed function: R100Button
        function R100ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' char(app.R100stimuli) ' &'];
            unix(str2)
        end

        % Value changed function: Stimulinumber13Spinner
        function Stimulinumber13SpinnerValueChanged(app, event)
            app.trialnum = app.Stimulinumber13Spinner.Value;
            
            if app.trialnum ==1
            disp(app.trialnum)
            app.R10stimuli = "R10_synthetic_vowel_A.wav";
            app.W1stimuli = "W1_noisy_A.wav";
            app.W2stimuli = "W2_noisy_A.wav";
            app.W4stimuli = "W4_noisy_A.wav";
            app.W6stimuli = "W6_noisy_A.wav";
            app.R100stimuli = "W6_natural_vowel_A.wav";
            
            
            elseif app.trialnum==2
                 
            app.R10stimuli = "W1_noisy_I.wav";
            app.W1stimuli = "W1_noisy_I.wav";
            app.W2stimuli = "W2_noisy_I.wav";
            app.W4stimuli = "W4_noisy_I.wav";
            app.W6stimuli = "W6_noisy_I.wav";
            app.R100stimuli = "W6_natural_vowel_I.wav";
                
           
            elseif app.trialnum==3
                
            app.R10stimuli = "W1_noisy_U.wav";
            app.W1stimuli = "W1_noisy_U.wav";
            app.W2stimuli = "W2_noisy_U.wav";
            app.W4stimuli = "W4_noisy_U.wav";
            app.W6stimuli = "W6_noisy_U.wav";
            app.R100stimuli = "W6_natural_vowel_U.wav";
            else
                disp("stimuli number wrong")
            end
            
        end

        % Callback function
        function ListenernameEditFieldValueChanged(app, event)
         value = app.ListenernameEditField.Value;   
         root =   '/home/kodhandarama/Desktop/Desktop/Analog/Shreeram_Chandra/shreeram/python_work/python_sounddevice/expt1_results';
         app.location =strcat(root,value);

        end

        % Value changed function: ListenerNameEditField
        function ListenerNameEditFieldValueChanged(app, event)
            value = app.ListenerNameEditField.Value;
            root =   '/home/kodhandarama/Desktop/Desktop/Analog/Shreeram_Chandra/shreeram/python_work/python_sounddevice/expt3_results/';
            app.location =strcat(root,value,'.txt')
            
            str = ['cd ' root];
            unix(str)
            %str2 = ['mkdir ' app.location];
            %unix(str2)
            
            fileID = fopen(app.location, 'a');

            fprintf(fileID,'%s \n',value);
            fclose(fileID);
            
            str3 = ['cd ' '/home/kodhandarama/Desktop/Desktop/Analog/Shreeram_Chandra/shreeram/python_work/python_sounddevice/Expt2/code/expt3'];
            unix(str3)
            
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        fileID = fopen(app.location, 'a');
        fprintf(fileID,'\n\n\n\n Trial Number :');
        fprintf(fileID,'%d\n',app.trialnum);
        fprintf(fileID,'____________________________________________\n');
        fprintf(fileID,'%s \n','Source 1 :');
        fprintf(fileID,'%s\t %d \n','R10 :',app.R10value_s1);
        fprintf(fileID,'%s\t %d \n','W1 :',app.W1value_s1);
        fprintf(fileID,'%s\t %d \n','W2 :',app.W2value_s1);
        fprintf(fileID,'%s\t %d \n','W4 :',app.W4value_s1);
        fprintf(fileID,'%s\t %d \n','W6 :',app.W6value_s1);
        fprintf(fileID,'%s\t %d ','R100 :',app.R100value_s1);
        fprintf(fileID,'\n\n\n%s\n','Source 2 :');
        fprintf(fileID,'%s\t %d \n','R10 :',app.R10value_s2);
        fprintf(fileID,'%s\t %d \n','W1 :',app.W1value_s2);
        fprintf(fileID,'%s\t %d \n','W2 :',app.W2value_s2);
        fprintf(fileID,'%s\t %d \n','W4 :',app.W4value_s2);
        fprintf(fileID,'%s\t %d \n','W6 :',app.W6value_s2);
        fprintf(fileID,'%s\t %d \n','R100 :',app.R100value_s2);
        fclose(fileID);


        %W2value;
        %W4value;
        %W6value;
        %R100value; 
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
           
       app.R10value_s2 = round(app.Slider_7.Value); % get then round
       app.Slider_7.Value = app.R10value_s2; % set
       disp(app.R10value_s2) 
        end

        % Value changed function: Slider_8
        function Slider_8ValueChanged(app, event)
       app.W1value_s2 = round(app.Slider_8.Value); % get then round
       app.Slider_8.Value = app.W1value_s2; % set
       disp(app.W1value_s2)
            
        end

        % Value changed function: Slider_9
        function Slider_9ValueChanged(app, event)
       app.W2value_s2 = round(app.Slider_9.Value); % get then round
       app.Slider_9.Value = app.W2value_s2; % set
       disp(app.W2value_s2)
            
        end

        % Value changed function: Slider_10
        function Slider_10ValueChanged(app, event)
            app.W4value_s2 = round(app.Slider_10.Value); % get then round
            app.Slider_10.Value = app.W4value_s2; % set
            disp(app.W4value_s2)
        end

        % Value changed function: Slider_11
        function Slider_11ValueChanged(app, event)
       app.W6value_s2 = round(app.Slider_11.Value); % get then round
       app.Slider_11.Value = app.W6value_s2; % set
       disp(app.W6value_s2)
            
        end

        % Value changed function: Slider_12
        function Slider_12ValueChanged(app, event)
       app.R100value_s2 = round(app.Slider_12.Value); % get then round
       app.Slider_12.Value = app.R100value_s2; % set
       disp(app.R100value_s2)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.R10Button = uibutton(app.UIFigure, 'push');
            app.R10Button.ButtonPushedFcn = createCallbackFcn(app, @R10ButtonPushed, true);
            app.R10Button.Text = 'R10';
            app.W1Button = uibutton(app.UIFigure, 'push');
            app.W1Button.ButtonPushedFcn = createCallbackFcn(app, @W1ButtonPushed, true);
            app.W1Button.Text = 'W1';
            app.W2Button = uibutton(app.UIFigure, 'push');
            app.W2Button.ButtonPushedFcn = createCallbackFcn(app, @W2ButtonPushed, true);
            app.W2Button.Text = 'W2';
            app.W4Button = uibutton(app.UIFigure, 'push');
            app.W4Button.ButtonPushedFcn = createCallbackFcn(app, @W4ButtonPushed, true);
            app.W4Button.Text = 'W4';
            app.W6Button = uibutton(app.UIFigure, 'push');
            app.W6Button.ButtonPushedFcn = createCallbackFcn(app, @W6ButtonPushed, true);
            app.W6Button.Text = 'W6';
            app.R100Button = uibutton(app.UIFigure, 'push');
            app.R100Button.ButtonPushedFcn = createCallbackFcn(app, @R100ButtonPushed, true);
            app.R100Button.Text = 'R100';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 120];
            app.Slider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 120];
            app.Slider_2.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 120];
            app.Slider_3.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [0 120];
            app.Slider_4.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [0 120];
            app.Slider_5.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_6 = uislider(app.UIFigure);
            app.Slider_6.Limits = [0 120];
            app.Slider_6.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Stimulinumber13SpinnerLabel = uilabel(app.UIFigure);
            app.Stimulinumber13SpinnerLabel.Text = 'Stimuli number (1 - 3) :';
            app.Stimulinumber13Spinner = uispinner(app.UIFigure);
            app.Stimulinumber13Spinner.Limits = [0 3];
            app.Stimulinumber13Spinner.ValueChangedFcn = createCallbackFcn(app, @Stimulinumber13SpinnerValueChanged, true);
            app.ListenerNameEditFieldLabel = uilabel(app.UIFigure);
            app.ListenerNameEditFieldLabel.Text = 'Listener Name :';
            app.ListenerNameEditField = uieditfield(app.UIFigure, 'text');
            app.ListenerNameEditField.ValueChangedFcn = createCallbackFcn(app, @ListenerNameEditFieldValueChanged, true);
            app.Slider_12 = uislider(app.UIFigure);
            app.Slider_12.Limits = [-10 120];
            app.Slider_12.MajorTicks = [-10 0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_12.ValueChangedFcn = createCallbackFcn(app, @Slider_12ValueChanged, true);
            app.Slider_12.Value = -10;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.Slider_11 = uislider(app.UIFigure);
            app.Slider_11.Limits = [-10 120];
            app.Slider_11.MajorTicks = [-10 0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_11.ValueChangedFcn = createCallbackFcn(app, @Slider_11ValueChanged, true);
            app.Slider_11.Value = -10;
            app.Slider_7 = uislider(app.UIFigure);
            app.Slider_7.Limits = [-10 120];
            app.Slider_7.MajorTicks = [-10 0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_7.Value = -10;
            app.Slider_10 = uislider(app.UIFigure);
            app.Slider_10.Limits = [-10 120];
            app.Slider_10.MajorTicks = [-10 0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_10.ValueChangedFcn = createCallbackFcn(app, @Slider_10ValueChanged, true);
            app.Slider_10.Value = -10;
            app.Slider_8 = uislider(app.UIFigure);
            app.Slider_8.Limits = [-10 120];
            app.Slider_8.MajorTicks = [-10 0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_8.ValueChangedFcn = createCallbackFcn(app, @Slider_8ValueChanged, true);
            app.Slider_8.Value = -10;
            app.Source1Label = uilabel(app.UIFigure);
            app.Source1Label.Text = 'Source 1';
            app.Source2Label = uilabel(app.UIFigure);
            app.Source2Label.Text = 'Source 2';
            app.Slider_9 = uislider(app.UIFigure);
            app.Slider_9.Limits = [-10 120];
            app.Slider_9.MajorTicks = [-10 0 10 20 30 40 50 60 70 80 90 100 110 120];
            app.Slider_9.ValueChangedFcn = createCallbackFcn(app, @Slider_9ValueChanged, true);
            app.Slider_9.Value = -10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = expt3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end