classdef generalised_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        W1AButton     matlab.ui.control.Button
        W4AButton     matlab.ui.control.Button
        W6AButton     matlab.ui.control.Button
        W2AButton     matlab.ui.control.Button
        W1IButton     matlab.ui.control.Button
        W1UButton     matlab.ui.control.Button
        Button_3      matlab.ui.control.Button
        Button_4      matlab.ui.control.Button
        Button_5      matlab.ui.control.Button
        Button_6      matlab.ui.control.Button
        R100Button    matlab.ui.control.Button
        W2IButton     matlab.ui.control.Button
        W2UButton     matlab.ui.control.Button
        Button_9      matlab.ui.control.Button
        Button_10     matlab.ui.control.Button
        Button_11     matlab.ui.control.Button
        Button_12     matlab.ui.control.Button
        R100Button_2  matlab.ui.control.Button
        W4IButton     matlab.ui.control.Button
        W4UButton     matlab.ui.control.Button
        Button_15     matlab.ui.control.Button
        Button_16     matlab.ui.control.Button
        Button_17     matlab.ui.control.Button
        Button_18     matlab.ui.control.Button
        R100Button_3  matlab.ui.control.Button
        W6IButton     matlab.ui.control.Button
        W6UButton     matlab.ui.control.Button
        Button_21     matlab.ui.control.Button
        Button_22     matlab.ui.control.Button
        Button_23     matlab.ui.control.Button
        Button_24     matlab.ui.control.Button
        R100Button_4  matlab.ui.control.Button
        SelectButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        original_files % Description
        path_directory % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: W1AButton
        function W1AButtonPushed(app, event)
            filename=[app.path_directory '/' app.original_files(1).name]
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' filename ' &'];
            unix(str2)
        end

        % Button pushed function: W2AButton
        function W2AButtonPushed(app, event)
           filename=[app.path_directory '/' app.original_files(4).name]
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' filename ' &'];
            unix(str2)
        end

        % Button pushed function: W4AButton
        function W4AButtonPushed(app, event)
       filename=[app.path_directory '/' app.original_files(7).name]
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' filename ' &'];
            unix(str2)
        end

        % Button pushed function: W6AButton
        function W6AButtonPushed(app, event)
           filename=[app.path_directory '/' app.original_files(10).name]
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' filename ' &'];
            unix(str2)
        end

        % Button pushed function: W1IButton
        function W1IButtonPushed(app, event)
            filename=[app.path_directory '/' app.original_files(2).name]
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' filename ' &'];
            unix(str2)
        end

        % Button pushed function: W1UButton
        function W1UButtonPushed(app, event)
             filename=[app.path_directory '/' app.original_files(3).name]
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' filename ' &'];
            unix(str2)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_s_30.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_s_20.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_s_10.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_s_0.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: W2IButton
        function W2IButtonPushed(app, event)
            filename=[app.path_directory '/' app.original_files(5).name]
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' filename ' &'];
            unix(str2)
        end

        % Button pushed function: W2UButton
        function W2UButtonPushed(app, event)
             filename=[app.path_directory '/' app.original_files(6).name]
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' filename ' &'];
            unix(str2)
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_sby2_30.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_sby2_20.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_sby2_10.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_sby2_0.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: W4IButton
        function W4IButtonPushed(app, event)
            filename=[app.path_directory '/' app.original_files(8).name]
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' filename ' &'];
            unix(str2)
        end

        % Button pushed function: W4UButton
        function W4UButtonPushed(app, event)
             filename=[app.path_directory '/' app.original_files(9).name]
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' filename ' &'];
            unix(str2)
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_sby4_30.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_sby4_20.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_sby4_10.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_sby4_0.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: W6IButton
        function W6IButtonPushed(app, event)
            filename=[app.path_directory '/' app.original_files(11).name]
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' filename ' &'];
            unix(str2)
        end

        % Button pushed function: W6UButton
        function W6UButtonPushed(app, event)
             filename=[app.path_directory '/' app.original_files(12).name]
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' filename ' &'];
            unix(str2)
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_2s_30.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_2s_20.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_2s_10.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_2s_0.wav' ' &'];
            unix(str2)
        end

        % Callback function
        function Button_25Pushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_s_60.wav' ' &'];
            unix(str2)
        end

        % Callback function
        function Button_26Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_sby2_60.wav' ' &'];
            unix(str2)
        end

        % Callback function
        function Button_27Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_sby4_60.wav' ' &'];
            unix(str2)
        end

        % Callback function
        function Button_28Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f2_2s.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: R100Button
        function R100ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'W6_natural_vowel_A.wav' ' &'];
            unix(str2)


        end

        % Button pushed function: R100Button_2
        function R100Button_2Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'W6_natural_vowel_A.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: R100Button_3
        function R100Button_3Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'W6_natural_vowel_A.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: R100Button_4
        function R100Button_4Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'W6_natural_vowel_A.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            selpath = uigetdir;
            disp(selpath)
            
            app.path_directory=selpath;
% Pls note the format of files,change it as required
            app.original_files=dir([app.path_directory '/*.wav'])
            for k=1:length(app.original_files)
            filename=[app.path_directory '/' app.original_files(k).name]
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.W1AButton = uibutton(app.UIFigure, 'push');
            app.W1AButton.ButtonPushedFcn = createCallbackFcn(app, @W1AButtonPushed, true);
            app.W1AButton.Text = 'W1A';
            app.W4AButton = uibutton(app.UIFigure, 'push');
            app.W4AButton.ButtonPushedFcn = createCallbackFcn(app, @W4AButtonPushed, true);
            app.W4AButton.Text = 'W4A';
            app.W6AButton = uibutton(app.UIFigure, 'push');
            app.W6AButton.ButtonPushedFcn = createCallbackFcn(app, @W6AButtonPushed, true);
            app.W6AButton.Text = 'W6A';
            app.W2AButton = uibutton(app.UIFigure, 'push');
            app.W2AButton.ButtonPushedFcn = createCallbackFcn(app, @W2AButtonPushed, true);
            app.W2AButton.Text = 'W2A';
            app.W1IButton = uibutton(app.UIFigure, 'push');
            app.W1IButton.ButtonPushedFcn = createCallbackFcn(app, @W1IButtonPushed, true);
            app.W1IButton.Text = 'W1I';
            app.W1UButton = uibutton(app.UIFigure, 'push');
            app.W1UButton.ButtonPushedFcn = createCallbackFcn(app, @W1UButtonPushed, true);
            app.W1UButton.Text = 'W1U';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '-30';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-20';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '-10';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '0';
            app.R100Button = uibutton(app.UIFigure, 'push');
            app.R100Button.ButtonPushedFcn = createCallbackFcn(app, @R100ButtonPushed, true);
            app.R100Button.Text = {'R100'; ''};
            app.W2IButton = uibutton(app.UIFigure, 'push');
            app.W2IButton.ButtonPushedFcn = createCallbackFcn(app, @W2IButtonPushed, true);
            app.W2IButton.Text = 'W2I';
            app.W2UButton = uibutton(app.UIFigure, 'push');
            app.W2UButton.ButtonPushedFcn = createCallbackFcn(app, @W2UButtonPushed, true);
            app.W2UButton.Text = 'W2U';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '-30';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '-20';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '-10';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '0';
            app.R100Button_2 = uibutton(app.UIFigure, 'push');
            app.R100Button_2.ButtonPushedFcn = createCallbackFcn(app, @R100Button_2Pushed, true);
            app.R100Button_2.Text = {'R100'; ''};
            app.W4IButton = uibutton(app.UIFigure, 'push');
            app.W4IButton.ButtonPushedFcn = createCallbackFcn(app, @W4IButtonPushed, true);
            app.W4IButton.Text = 'W4I';
            app.W4UButton = uibutton(app.UIFigure, 'push');
            app.W4UButton.ButtonPushedFcn = createCallbackFcn(app, @W4UButtonPushed, true);
            app.W4UButton.Text = 'W4U';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '-30';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '-20';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '-10';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '0';
            app.R100Button_3 = uibutton(app.UIFigure, 'push');
            app.R100Button_3.ButtonPushedFcn = createCallbackFcn(app, @R100Button_3Pushed, true);
            app.R100Button_3.Text = {'R100'; ''};
            app.W6IButton = uibutton(app.UIFigure, 'push');
            app.W6IButton.ButtonPushedFcn = createCallbackFcn(app, @W6IButtonPushed, true);
            app.W6IButton.Text = 'W6I';
            app.W6UButton = uibutton(app.UIFigure, 'push');
            app.W6UButton.ButtonPushedFcn = createCallbackFcn(app, @W6UButtonPushed, true);
            app.W6UButton.Text = 'W6U';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '-30';
            app.Button_22 = uibutton(app.UIFigure, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '-20';
            app.Button_23 = uibutton(app.UIFigure, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '-10';
            app.Button_24 = uibutton(app.UIFigure, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Text = '0';
            app.R100Button_4 = uibutton(app.UIFigure, 'push');
            app.R100Button_4.ButtonPushedFcn = createCallbackFcn(app, @R100Button_4Pushed, true);
            app.R100Button_4.Text = {'R100'; ''};
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generalised_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end