classdef A_wgn_bandwidthmod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        AWGNButton    matlab.ui.control.Button
        A_s4Button    matlab.ui.control.Button
        A_2sButton    matlab.ui.control.Button
        A_s2Button    matlab.ui.control.Button
        Button        matlab.ui.control.Button
        Button_2      matlab.ui.control.Button
        Button_3      matlab.ui.control.Button
        Button_4      matlab.ui.control.Button
        Button_5      matlab.ui.control.Button
        Button_6      matlab.ui.control.Button
        R100Button    matlab.ui.control.Button
        Button_7      matlab.ui.control.Button
        Button_8      matlab.ui.control.Button
        Button_9      matlab.ui.control.Button
        Button_10     matlab.ui.control.Button
        Button_11     matlab.ui.control.Button
        Button_12     matlab.ui.control.Button
        R100Button_2  matlab.ui.control.Button
        Button_13     matlab.ui.control.Button
        Button_14     matlab.ui.control.Button
        Button_15     matlab.ui.control.Button
        Button_16     matlab.ui.control.Button
        Button_17     matlab.ui.control.Button
        Button_18     matlab.ui.control.Button
        R100Button_3  matlab.ui.control.Button
        Button_19     matlab.ui.control.Button
        Button_20     matlab.ui.control.Button
        Button_21     matlab.ui.control.Button
        Button_22     matlab.ui.control.Button
        Button_23     matlab.ui.control.Button
        Button_24     matlab.ui.control.Button
        R100Button_4  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AWGNButton
        function AWGNButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_s_R10.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: A_s2Button
        function A_s2ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby2_R10.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: A_s4Button
        function A_s4ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby4_R10.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: A_2sButton
        function A_2sButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_2s_R10.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_s_50.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_s_40.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_s_30.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_s_20.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_s_10.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_s_0.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby2_50.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby2_40.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby2_30.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby2_20.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby2_10.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby2_0.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby4_50.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby4_40.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby4_30.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby4_20.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby4_10.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_sby4_0.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_2s_50.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_2s_40.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_2s_30.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_2s_20.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_2s_10.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
               str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'A_wgn_2s_0.wav' ' &'];
            unix(str2)
        end

        % Callback function
        function Button_25Pushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f1_s_60.wav' ' &'];
            unix(str2)
        end

        % Callback function
        function Button_26Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f1_sby2_60.wav' ' &'];
            unix(str2)
        end

        % Callback function
        function Button_27Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f1_sby4_60.wav' ' &'];
            unix(str2)
        end

        % Callback function
        function Button_28Pushed(app, event)
             str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'a_f1_2s.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: R100Button
        function R100ButtonPushed(app, event)
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'W6_natural_vowel_A.wav' ' &'];
            unix(str2)


        end

        % Button pushed function: R100Button_2
        function R100Button_2Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'W6_natural_vowel_A.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: R100Button_3
        function R100Button_3Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'W6_natural_vowel_A.wav' ' &'];
            unix(str2)
        end

        % Button pushed function: R100Button_4
        function R100Button_4Pushed(app, event)
              str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' 'W6_natural_vowel_A.wav' ' &'];
            unix(str2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AWGNButton = uibutton(app.UIFigure, 'push');
            app.AWGNButton.ButtonPushedFcn = createCallbackFcn(app, @AWGNButtonPushed, true);
            app.AWGNButton.Text = 'AWGN';
            app.A_s4Button = uibutton(app.UIFigure, 'push');
            app.A_s4Button.ButtonPushedFcn = createCallbackFcn(app, @A_s4ButtonPushed, true);
            app.A_s4Button.Text = {'A_s/4'; ''};
            app.A_2sButton = uibutton(app.UIFigure, 'push');
            app.A_2sButton.ButtonPushedFcn = createCallbackFcn(app, @A_2sButtonPushed, true);
            app.A_2sButton.Text = 'A_2s';
            app.A_s2Button = uibutton(app.UIFigure, 'push');
            app.A_s2Button.ButtonPushedFcn = createCallbackFcn(app, @A_s2ButtonPushed, true);
            app.A_s2Button.Text = 'A_s/2';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '-50';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-40';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '-30';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-20';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '-10';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '0';
            app.R100Button = uibutton(app.UIFigure, 'push');
            app.R100Button.ButtonPushedFcn = createCallbackFcn(app, @R100ButtonPushed, true);
            app.R100Button.Text = {'R100'; ''};
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '-50';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '-40';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '-30';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '-20';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '-10';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '0';
            app.R100Button_2 = uibutton(app.UIFigure, 'push');
            app.R100Button_2.ButtonPushedFcn = createCallbackFcn(app, @R100Button_2Pushed, true);
            app.R100Button_2.Text = {'R100'; ''};
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '-50';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '-40';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '-30';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '-20';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '-10';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '0';
            app.R100Button_3 = uibutton(app.UIFigure, 'push');
            app.R100Button_3.ButtonPushedFcn = createCallbackFcn(app, @R100Button_3Pushed, true);
            app.R100Button_3.Text = {'R100'; ''};
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '-50';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '-40';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '-30';
            app.Button_22 = uibutton(app.UIFigure, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '-20';
            app.Button_23 = uibutton(app.UIFigure, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '-10';
            app.Button_24 = uibutton(app.UIFigure, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Text = '0';
            app.R100Button_4 = uibutton(app.UIFigure, 'push');
            app.R100Button_4.ButtonPushedFcn = createCallbackFcn(app, @R100Button_4Pushed, true);
            app.R100Button_4.Text = {'R100'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A_wgn_bandwidthmod

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end