classdef Expt2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Ref1Button                 matlab.ui.control.Button
        TestButton                 matlab.ui.control.Button
        Ref2Button                 matlab.ui.control.Button
        LocalisedButton            matlab.ui.control.Button
        NotlocalisedButton         matlab.ui.control.Button
        Ref1pathButton             matlab.ui.control.Button
        TestpathButton             matlab.ui.control.Button
        Ref2pathButton             matlab.ui.control.Button
        RunninginbackgroundButton  matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        LocalisedOB500Button       matlab.ui.control.Button
        NotLocalisedOB500Button    matlab.ui.control.Button
        LocalisedOB250Button       matlab.ui.control.Button
        NotLocalisedOB250Button    matlab.ui.control.Button
        LocalisedOB1000Button      matlab.ui.control.Button
        NotlocalisedOB1000Button   matlab.ui.control.Button
        LocalisedOB2000Button      matlab.ui.control.Button
        NotlocalisedOB2000Button   matlab.ui.control.Button
    end

    
    properties (Access = private)
        ref1path
        testpath
        ref2path
        snr=100 % Description
        threshold=[]
        count = 1
        meanthreshold
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Ref1Button
        function Ref1ButtonPushed(app, event)
            ref1 = app.ref1path
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' ref1 ' &'];
            unix(str2)
          %  unix(str)
        end

        % Button pushed function: Ref1pathButton
        function Ref1pathButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            app.ref1path = fullfile(path,file);
         %   set(app.Ref1pathButton,'Enable','off');
        end

        % Button pushed function: TestpathButton
        function TestpathButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            app.testpath = fullfile(path,file);
        %    set(app.TestpathButton,'Enable','off');
        end

        % Button pushed function: Ref2pathButton
        function Ref2pathButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            app.ref2path = fullfile(path,file);
          %  set(app.Ref2pathButton,'Enable','off');
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
           if (app.snr == 100)
            
            app.snr
            test = app.testpath
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' test ' &'];
            unix(str2)
            set(app.RunninginbackgroundButton,'Backgroundcolor','r');
           else
            app.snr
            test = 'exptstimuli.wav'
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py exptstimuli.wav ' ' &'];
            unix(str2)
            set(app.RunninginbackgroundButton,'Backgroundcolor','r'); 
               
           end
           
        end

        % Button pushed function: Ref2Button
        function Ref2ButtonPushed(app, event)
            ref2 = app.ref2path
            str = ['killall python3'];
            unix(str)
            str2 = ['python3 play_8chn.py ' ref2 ' &'];
            unix(str2)
        end

        % Button pushed function: LocalisedButton
        function LocalisedButtonPushed(app, event)
            % set(app.RunninginbackgroundButton,'Enable','off')
            %app.RunninginbackgroundButton.BackgroundColor =    'Red';
%             set(app.NotlocalisedButton,'Enable','off')
            set(app.RunninginbackgroundButton,'Backgroundcolor','b');
            delta_snr= 3;
            disp("Reducing SNR");
            if(app.snr==100)
                app.snr=30;
            else
                app.snr = app.snr - delta_snr;
            end
            disp(app.snr);
           % file_name = "temp7.wav";
            %unix_command = ["matlab -nodisplay -nojvm -r 'synthesis_vowel( "  file_name ',' app.snr '); exit;']
            %unix(unix_command)
       
            %status = system(['matlab' '-nodisplay' '-nojvm' '-r' 'synthesis_vowel' "filename.wav" app.snr  ';' 'exit' ';'])
            %status=system("matlab -nodisplay -nojvm -r 'synthesis_vowel "basic.wav" Arg2; exit;'")
            
            
            command= strcat("matlab -nodisplay -nojvm -r 'synthesis_vowel(", ' "temp8.wav" ',",",int2str(app.snr),");exit;'")
            system(command)
            
            command2= "matlab -nodisplay -nojvm -r 'generated_decorrelated_signals();exit;'"
            system(command2)
            
%             set(app.LocalisedButton,'Enable','on');
%             set(app.NotlocalisedButton,'Enable','on');
               set(app.RunninginbackgroundButton,'Backgroundcolor','g');
            % set(app.RunninginbackgroundButton,'Enable','on')
        end

        % Button pushed function: NotlocalisedButton
        function NotlocalisedButtonPushed(app, event)
%             set(app.LocalisedButton,'Enable','off');
%             set(app.NotlocalisedButton,'Enable','off');
        %    set(app.RunninginbackgroundButton,'Enable','off')
          delta_snr= 3;
          if(app.count~=4)
            app.count = app.count+1;
            app.threshold(end+1) = app.snr;
        
            if(app.snr> 0)
            app.snr = app.snr + 3*delta_snr;
            end
            if(app.snr < 0)
             app.snr = app.snr + 3*delta_snr;
            end
            if(app.snr == 0)
               app.snr =app.snr + 5 ;
            end
            command= strcat("matlab -nodisplay -nojvm -r 'synthesis_vowel(", ' "temp8.wav" ',",",int2str(app.snr),");exit;'")
            system(command)
            
            command2= "matlab -nodisplay -nojvm -r 'generated_decorrelated_signals();exit;'"
            system(command2)
          end
      
            if(app.count==4)
            set(app.LocalisedButton,'Enable','off');
            set(app.NotlocalisedButton,'Enable','off');
            disp("Mean threshold")
            app.meanthreshold = mean(app.threshold);
            disp(app.meanthreshold)
            end
         set(app.RunninginbackgroundButton,'Backgroundcolor','g');
        %set(app.RunninginbackgroundButton,'Enable','on')
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.snr =100;
        end

        % Button pushed function: LocalisedOB500Button
        function LocalisedOB500ButtonPushed(app, event)
            % set(app.RunninginbackgroundButton,'Enable','off')
            %app.RunninginbackgroundButton.BackgroundColor =    'Red';
%             set(app.NotlocalisedButton,'Enable','off')
            set(app.RunninginbackgroundButton,'Backgroundcolor','b');
            delta_snr= 3;
            disp("Reducing SNR");
            if(app.snr==100)
                app.snr=30;
            else
                app.snr = app.snr - delta_snr;
            end
            disp(app.snr);
           % file_name = "temp7.wav";
            %unix_command = ["matlab -nodisplay -nojvm -r 'synthesis_vowel( "  file_name ',' app.snr '); exit;']
            %unix(unix_command)
       
            %status = system(['matlab' '-nodisplay' '-nojvm' '-r' 'synthesis_vowel' "filename.wav" app.snr  ';' 'exit' ';'])
            %status=system("matlab -nodisplay -nojvm -r 'synthesis_vowel "basic.wav" Arg2; exit;'")
            
            
            command= strcat("matlab -nodisplay -nojvm -r 'synthesis_vowel_OB500(", ' "temp8.wav" ',",",int2str(app.snr),");exit;'")
            system(command)
            
            command2= "matlab -nodisplay -nojvm -r 'generated_decorrelated_signals();exit;'"
            system(command2)
            
%             set(app.LocalisedButton,'Enable','on');
%             set(app.NotlocalisedButton,'Enable','on');
               set(app.RunninginbackgroundButton,'Backgroundcolor','g');
            % set(app.RunninginbackgroundButton,'Enable','on')
        end

        % Button pushed function: NotLocalisedOB500Button
        function NotLocalisedOB500ButtonPushed(app, event)
            %             set(app.LocalisedButton,'Enable','off');
%             set(app.NotlocalisedButton,'Enable','off');
        %    set(app.RunninginbackgroundButton,'Enable','off')
          delta_snr= 3;
          if(app.count~=4)
            app.count = app.count+1;
            app.threshold(end+1) = app.snr;
        
            if(app.snr> 0)
            app.snr = app.snr + 3*delta_snr;
            end
            if(app.snr < 0)
             app.snr = app.snr + 3*delta_snr;
            end
            if(app.snr == 0)
               app.snr =app.snr + 5 ;
            end
            command= strcat("matlab -nodisplay -nojvm -r 'synthesis_vowel_OB500(", ' "temp8.wav" ',",",int2str(app.snr),");exit;'")
            system(command)
            
            command2= "matlab -nodisplay -nojvm -r 'generated_decorrelated_signals();exit;'"
            system(command2)
          end
      
            if(app.count==4)
            set(app.LocalisedButton,'Enable','off');
            set(app.NotlocalisedButton,'Enable','off');
            disp("Mean threshold")
            app.meanthreshold = mean(app.threshold);
            disp(app.meanthreshold)
            end
         set(app.RunninginbackgroundButton,'Backgroundcolor','g');
        %set(app.RunninginbackgroundButton,'Enable','on')
        end

        % Button pushed function: LocalisedOB250Button
        function LocalisedOB250ButtonPushed(app, event)
            % set(app.RunninginbackgroundButton,'Enable','off')
            %app.RunninginbackgroundButton.BackgroundColor =    'Red';
%             set(app.NotlocalisedButton,'Enable','off')
            set(app.RunninginbackgroundButton,'Backgroundcolor','b');
            delta_snr= 3;
            disp("Reducing SNR");
            if(app.snr==100)
                app.snr=30;
            else
                app.snr = app.snr - delta_snr;
            end
            disp(app.snr);
           % file_name = "temp7.wav";
            %unix_command = ["matlab -nodisplay -nojvm -r 'synthesis_vowel( "  file_name ',' app.snr '); exit;']
            %unix(unix_command)
       
            %status = system(['matlab' '-nodisplay' '-nojvm' '-r' 'synthesis_vowel' "filename.wav" app.snr  ';' 'exit' ';'])
            %status=system("matlab -nodisplay -nojvm -r 'synthesis_vowel "basic.wav" Arg2; exit;'")
            
            
            command= strcat("matlab -nodisplay -nojvm -r 'synthesis_vowel_OB250(", ' "temp8.wav" ',",",int2str(app.snr),");exit;'")
            system(command)
            
            command2= "matlab -nodisplay -nojvm -r 'generated_decorrelated_signals();exit;'"
            system(command2)
            
%             set(app.LocalisedButton,'Enable','on');
%             set(app.NotlocalisedButton,'Enable','on');
               set(app.RunninginbackgroundButton,'Backgroundcolor','g');
            % set(app.RunninginbackgroundButton,'Enable','on')
        end

        % Button pushed function: LocalisedOB1000Button
        function LocalisedOB1000ButtonPushed(app, event)
                        % set(app.RunninginbackgroundButton,'Enable','off')
            %app.RunninginbackgroundButton.BackgroundColor =    'Red';
%             set(app.NotlocalisedButton,'Enable','off')
            set(app.RunninginbackgroundButton,'Backgroundcolor','b');
            delta_snr= 3;
            disp("Reducing SNR");
            if(app.snr==100)
                app.snr=30;
            else
                app.snr = app.snr - delta_snr;
            end
            disp(app.snr);
           % file_name = "temp7.wav";
            %unix_command = ["matlab -nodisplay -nojvm -r 'synthesis_vowel( "  file_name ',' app.snr '); exit;']
            %unix(unix_command)
       
            %status = system(['matlab' '-nodisplay' '-nojvm' '-r' 'synthesis_vowel' "filename.wav" app.snr  ';' 'exit' ';'])
            %status=system("matlab -nodisplay -nojvm -r 'synthesis_vowel "basic.wav" Arg2; exit;'")
            
            
            command= strcat("matlab -nodisplay -nojvm -r 'synthesis_vowel_OB1000(", ' "temp8.wav" ',",",int2str(app.snr),");exit;'")
            system(command)
            
            command2= "matlab -nodisplay -nojvm -r 'generated_decorrelated_signals();exit;'"
            system(command2)
            
%             set(app.LocalisedButton,'Enable','on');
%             set(app.NotlocalisedButton,'Enable','on');
               set(app.RunninginbackgroundButton,'Backgroundcolor','g');
            % set(app.RunninginbackgroundButton,'Enable','on')
        end

        % Button pushed function: NotLocalisedOB250Button
        function NotLocalisedOB250ButtonPushed(app, event)
                %             set(app.LocalisedButton,'Enable','off');
%             set(app.NotlocalisedButton,'Enable','off');
        %    set(app.RunninginbackgroundButton,'Enable','off')
          delta_snr= 3;
          if(app.count~=4)
            app.count = app.count+1;
            app.threshold(end+1) = app.snr;
        
            if(app.snr> 0)
            app.snr = app.snr + 3*delta_snr;
            end
            if(app.snr < 0)
             app.snr = app.snr + 3*delta_snr;
            end
            if(app.snr == 0)
               app.snr =app.snr + 5 ;
            end
            command= strcat("matlab -nodisplay -nojvm -r 'synthesis_vowel_OB250(", ' "temp8.wav" ',",",int2str(app.snr),");exit;'")
            system(command)
            
            command2= "matlab -nodisplay -nojvm -r 'generated_decorrelated_signals();exit;'"
            system(command2)
          end
      
            if(app.count==4)
            set(app.LocalisedButton,'Enable','off');
            set(app.NotlocalisedButton,'Enable','off');
            disp("Mean threshold")
            app.meanthreshold = mean(app.threshold);
            disp(app.meanthreshold)
            end
         set(app.RunninginbackgroundButton,'Backgroundcolor','g');
        %set(app.RunninginbackgroundButton,'Enable','on')
        end

        % Button pushed function: NotlocalisedOB1000Button
        function NotlocalisedOB1000ButtonPushed(app, event)
                %             set(app.LocalisedButton,'Enable','off');
%             set(app.NotlocalisedButton,'Enable','off');
        %    set(app.RunninginbackgroundButton,'Enable','off')
          delta_snr= 3;
          if(app.count~=4)
            app.count = app.count+1;
            app.threshold(end+1) = app.snr;
        
            if(app.snr> 0)
            app.snr = app.snr + 3*delta_snr;
            end
            if(app.snr < 0)
             app.snr = app.snr + 3*delta_snr;
            end
            if(app.snr == 0)
               app.snr =app.snr + 5 ;
            end
            command= strcat("matlab -nodisplay -nojvm -r 'synthesis_vowel_OB1000(", ' "temp8.wav" ',",",int2str(app.snr),");exit;'")
            system(command)
            
            command2= "matlab -nodisplay -nojvm -r 'generated_decorrelated_signals();exit;'"
            system(command2)
          end
      
            if(app.count==4)
            set(app.LocalisedButton,'Enable','off');
            set(app.NotlocalisedButton,'Enable','off');
            disp("Mean threshold")
            app.meanthreshold = mean(app.threshold);
            disp(app.meanthreshold)
            end
         set(app.RunninginbackgroundButton,'Backgroundcolor','g');
        %set(app.RunninginbackgroundButton,'Enable','on')
        end

        % Button pushed function: LocalisedOB2000Button
        function LocalisedOB2000ButtonPushed(app, event)
                           % set(app.RunninginbackgroundButton,'Enable','off')
            %app.RunninginbackgroundButton.BackgroundColor =    'Red';
%             set(app.NotlocalisedButton,'Enable','off')
            set(app.RunninginbackgroundButton,'Backgroundcolor','b');
            delta_snr= 3;
            disp("Reducing SNR");
            if(app.snr==100)
                app.snr=30;
            else
                app.snr = app.snr - delta_snr;
            end
            disp(app.snr);
           % file_name = "temp7.wav";
            %unix_command = ["matlab -nodisplay -nojvm -r 'synthesis_vowel( "  file_name ',' app.snr '); exit;']
            %unix(unix_command)
       
            %status = system(['matlab' '-nodisplay' '-nojvm' '-r' 'synthesis_vowel' "filename.wav" app.snr  ';' 'exit' ';'])
            %status=system("matlab -nodisplay -nojvm -r 'synthesis_vowel "basic.wav" Arg2; exit;'")
            
            
            command= strcat("matlab -nodisplay -nojvm -r 'synthesis_vowel_OB2000(", ' "temp8.wav" ',",",int2str(app.snr),");exit;'")
            system(command)
            
            command2= "matlab -nodisplay -nojvm -r 'generated_decorrelated_signals();exit;'"
            system(command2)
            
%             set(app.LocalisedButton,'Enable','on');
%             set(app.NotlocalisedButton,'Enable','on');
               set(app.RunninginbackgroundButton,'Backgroundcolor','g');
            % set(app.RunninginbackgroundButton,'Enable','on')
        end

        % Button pushed function: NotlocalisedOB2000Button
        function NotlocalisedOB2000ButtonPushed(app, event)
                %             set(app.LocalisedButton,'Enable','off');
%             set(app.NotlocalisedButton,'Enable','off');
        %    set(app.RunninginbackgroundButton,'Enable','off')
          delta_snr= 3;
          if(app.count~=4)
            app.count = app.count+1;
            app.threshold(end+1) = app.snr;
        
            if(app.snr> 0)
            app.snr = app.snr + 3*delta_snr;
            end
            if(app.snr < 0)
             app.snr = app.snr + 3*delta_snr;
            end
            if(app.snr == 0)
               app.snr =app.snr + 5 ;
            end
            command= strcat("matlab -nodisplay -nojvm -r 'synthesis_vowel_OB2000(", ' "temp8.wav" ',",",int2str(app.snr),");exit;'")
            system(command)
            
            command2= "matlab -nodisplay -nojvm -r 'generated_decorrelated_signals();exit;'"
            system(command2)
          end
      
            if(app.count==4)
            set(app.LocalisedButton,'Enable','off');
            set(app.NotlocalisedButton,'Enable','off');
            disp("Mean threshold")
            app.meanthreshold = mean(app.threshold);
            disp(app.meanthreshold)
            end
         set(app.RunninginbackgroundButton,'Backgroundcolor','g');
        %set(app.RunninginbackgroundButton,'Enable','on')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Ref1Button = uibutton(app.UIFigure, 'push');
            app.Ref1Button.ButtonPushedFcn = createCallbackFcn(app, @Ref1ButtonPushed, true);
            app.Ref1Button.Text = {'Ref1'; ''};
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.Ref2Button = uibutton(app.UIFigure, 'push');
            app.Ref2Button.ButtonPushedFcn = createCallbackFcn(app, @Ref2ButtonPushed, true);
            app.Ref2Button.Text = {'Ref2'; ''};
            app.LocalisedButton = uibutton(app.UIFigure, 'push');
            app.LocalisedButton.ButtonPushedFcn = createCallbackFcn(app, @LocalisedButtonPushed, true);
            app.LocalisedButton.Text = 'Localised';
            app.NotlocalisedButton = uibutton(app.UIFigure, 'push');
            app.NotlocalisedButton.ButtonPushedFcn = createCallbackFcn(app, @NotlocalisedButtonPushed, true);
            app.NotlocalisedButton.Text = {'Not localised'; ''};
            app.Ref1pathButton = uibutton(app.UIFigure, 'push');
            app.Ref1pathButton.ButtonPushedFcn = createCallbackFcn(app, @Ref1pathButtonPushed, true);
            app.Ref1pathButton.Text = 'Ref1 path';
            app.TestpathButton = uibutton(app.UIFigure, 'push');
            app.TestpathButton.ButtonPushedFcn = createCallbackFcn(app, @TestpathButtonPushed, true);
            app.TestpathButton.Text = 'Test path';
            app.Ref2pathButton = uibutton(app.UIFigure, 'push');
            app.Ref2pathButton.ButtonPushedFcn = createCallbackFcn(app, @Ref2pathButtonPushed, true);
            app.Ref2pathButton.Text = 'Ref2 path';
            app.RunninginbackgroundButton = uibutton(app.UIFigure, 'push');
            app.RunninginbackgroundButton.Text = {'Running in background'; ''};
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = {'Reset'; ''};
            app.LocalisedOB500Button = uibutton(app.UIFigure, 'push');
            app.LocalisedOB500Button.ButtonPushedFcn = createCallbackFcn(app, @LocalisedOB500ButtonPushed, true);
            app.LocalisedOB500Button.Text = {'Localised OB500'; ''};
            app.NotLocalisedOB500Button = uibutton(app.UIFigure, 'push');
            app.NotLocalisedOB500Button.ButtonPushedFcn = createCallbackFcn(app, @NotLocalisedOB500ButtonPushed, true);
            app.NotLocalisedOB500Button.Text = {'Not Localised '; 'OB500'};
            app.LocalisedOB250Button = uibutton(app.UIFigure, 'push');
            app.LocalisedOB250Button.ButtonPushedFcn = createCallbackFcn(app, @LocalisedOB250ButtonPushed, true);
            app.LocalisedOB250Button.Text = {'Localised OB250'; ''};
            app.NotLocalisedOB250Button = uibutton(app.UIFigure, 'push');
            app.NotLocalisedOB250Button.ButtonPushedFcn = createCallbackFcn(app, @NotLocalisedOB250ButtonPushed, true);
            app.NotLocalisedOB250Button.Text = 'Not Localised OB250';
            app.LocalisedOB1000Button = uibutton(app.UIFigure, 'push');
            app.LocalisedOB1000Button.ButtonPushedFcn = createCallbackFcn(app, @LocalisedOB1000ButtonPushed, true);
            app.LocalisedOB1000Button.Text = {'Localised OB1000'; ''};
            app.NotlocalisedOB1000Button = uibutton(app.UIFigure, 'push');
            app.NotlocalisedOB1000Button.ButtonPushedFcn = createCallbackFcn(app, @NotlocalisedOB1000ButtonPushed, true);
            app.NotlocalisedOB1000Button.Text = 'Not localised OB1000';
            app.LocalisedOB2000Button = uibutton(app.UIFigure, 'push');
            app.LocalisedOB2000Button.ButtonPushedFcn = createCallbackFcn(app, @LocalisedOB2000ButtonPushed, true);
            app.LocalisedOB2000Button.Text = 'Localised OB2000';
            app.NotlocalisedOB2000Button = uibutton(app.UIFigure, 'push');
            app.NotlocalisedOB2000Button.ButtonPushedFcn = createCallbackFcn(app, @NotlocalisedOB2000ButtonPushed, true);
            app.NotlocalisedOB2000Button.Text = 'Not localised OB2000';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Expt2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end