classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        R10Button                      matlab.ui.control.Button
        W1Button                       matlab.ui.control.Button
        W2Button                       matlab.ui.control.Button
        W4Button                       matlab.ui.control.Button
        W6Button                       matlab.ui.control.Button
        R100Button                     matlab.ui.control.Button
        Slider                         matlab.ui.control.Slider
        Slider_2                       matlab.ui.control.Slider
        Slider_3                       matlab.ui.control.Slider
        Slider_4                       matlab.ui.control.Slider
        Slider_5                       matlab.ui.control.Slider
        Slider_6                       matlab.ui.control.Slider
        SaveButton                     matlab.ui.control.Button
        Stimulinumber16EditFieldLabel  matlab.ui.control.Label
        Stimulinumber16EditField       matlab.ui.control.NumericEditField
        ListenernameLabel              matlab.ui.control.Label
        ListenernameEditField          matlab.ui.control.EditField
        ChangeStimuliButton            matlab.ui.control.Button
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    
    properties (Access = private)
        R10value;
        W1value;
        W2value;
        W4value;
        W6value;
        R100value; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            %value = app.Slider.Value;
            app.R10value = round(app.Slider.Value); % get then round
            app.Slider.Value = app.R10value; % set
            disp(app.R10value)
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.W1value = round(app.Slider_2.Value); % get then round
            app.Slider_2.Value = app.W1value; % set
            disp(app.W1value)    
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
        app.W2value = round(app.Slider_3.Value); % get then round
        app.Slider_3.Value = app.W2value; % set
        disp(app.W2value) 
            
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
       app.W4value = round(app.Slider_4.Value); % get then round
       app.Slider_4.Value = app.W4value; % set
       disp(app.W4value) 
            
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
       app.W6value = round(app.Slider_5.Value); % get then round
       app.Slider_5.Value = app.W6value; % set
       disp(app.W6value)  
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
       app.R100value = round(app.Slider_6.Value); % get then round
       app.Slider_6.Value = app.R100value; % set
       disp(app.R100value) 
        end

        % Value changed function: Stimulinumber16EditField
        function Stimulinumber16EditFieldValueChanged(app, event)
            value = app.Stimulinumber16EditField.Value;
            if value ==1
            disp(value)
            elseif value==2
            disp(value)
            elseif value==3
                
            elseif value==4
            
            elseif value==5
                
            elseif value==6
            
            else
                disp("stimuli number wrong")
            end
        end

        % Callback function
        function StateButtonValueChanged(app, event)
         app.StateButton.Value = app.Stimulinumber16EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.R10Button = uibutton(app.UIFigure, 'push');
            app.R10Button.Text = 'R10';
            app.W1Button = uibutton(app.UIFigure, 'push');
            app.W1Button.Text = 'W1';
            app.W2Button = uibutton(app.UIFigure, 'push');
            app.W2Button.Text = 'W2';
            app.W4Button = uibutton(app.UIFigure, 'push');
            app.W4Button.Text = 'W4';
            app.W6Button = uibutton(app.UIFigure, 'push');
            app.W6Button.Text = 'W6';
            app.R100Button = uibutton(app.UIFigure, 'push');
            app.R100Button.Text = 'R100';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 120];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 120];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 120];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [0 120];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [0 120];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_6 = uislider(app.UIFigure);
            app.Slider_6.Limits = [0 120];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.Stimulinumber16EditFieldLabel = uilabel(app.UIFigure);
            app.Stimulinumber16EditFieldLabel.Text = 'Stimuli number : (1-6)';
            app.Stimulinumber16EditField = uieditfield(app.UIFigure, 'numeric');
            app.Stimulinumber16EditField.Limits = [1 6];
            app.Stimulinumber16EditField.ValueChangedFcn = createCallbackFcn(app, @Stimulinumber16EditFieldValueChanged, true);
            app.Stimulinumber16EditField.Value = 1;
            app.ListenernameLabel = uilabel(app.UIFigure);
            app.ListenernameLabel.Text = 'Listener name :';
            app.ListenernameEditField = uieditfield(app.UIFigure, 'text');
            app.ChangeStimuliButton = uibutton(app.UIFigure, 'push');
            app.ChangeStimuliButton.Text = 'Change Stimuli';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end